classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Photo_Album_Organizer         matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        AddImageTab                   matlab.ui.container.Tab
        SelectPathEditFieldLabel      matlab.ui.control.Label
        SelectPathEditField           matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.Button
        AddButton                     matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        RecognitionVerificationPanel  matlab.ui.container.Panel
        YesButton                     matlab.ui.control.Button
        NoButton                      matlab.ui.control.Button
        SearchImageTab                matlab.ui.container.Tab
        EnterTagEditFieldLabel        matlab.ui.control.Label
        EnterTagEditField             matlab.ui.control.EditField
        SearchButton                  matlab.ui.control.Button
        CreateAlbumButton             matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.XAxis.Visible = 'off' ;
            app.UIAxes.XAxis.Visible = 'off' ;
            app.TextArea.Visible = 'off' ;
            app.RecognitionVerificationPanel.Visible = 'off' ;
            if (exist('Images','dir') == 0)
                mkdir('Images');
            end
            
        end

        % Button pushed function: AddButton
        function AddImage(app, event)
            global ImageName ;
            global PathName ;
            global ImageDir ;
            global NewImage  ;
            global FullName ;
            global tag;
            
            ImageDir  = dir('.\Images') ;
            NewImage = imread(app.SelectPathEditField.Value);
            RI = imref2d(size(NewImage)) ;
            RI.XWorldLimits = [0 1];
            RI.YWorldLimits = [0 1];
            imshow(NewImage,RI,'Parent',app.UIAxes) ;
            %imshow(NewImage,'Parent',app.UIAxes) ;
            FullName = strcat('.\Images\',ImageName) ;
            T = CreateDatabase(ImageDir) ;
            
            if ((length(ImageDir)-2) < 4)
                prompt = {'Add tag for selected image','Additional tag (Optional)'};
                dlg_title = 'Tag Input';
                num_lines= 1;
                def = {'',''} ;
                options.WindowStyle = 'normal' ;
                input  = inputdlg(prompt,dlg_title,num_lines,def,options);
                display(input) ;
                tag1 = input{1} ;
                tag2 = input{2} ;
                tag = strcat(input{1},'~') ;
                disp(tag) ;
                if (strcmp(input{2},'') ~= 1)
                    tag = strcat(tag,input{2}) ;
                    tag = strcat(tag,'~') ;
                end
                imwrite(NewImage,FullName,'jpg','Comment',tag) ;
                iminfo = imfinfo(FullName);
                app.TextArea.Visible = 'on'    ;
                app.TextArea.Value = {'Image tagged as :';tag1;tag2} ;
       
                T = CreateDatabase(ImageDir) ;
                disp(tag) ;
            else
               [m, A, Eigenfaces] = EigenfaceCore(T);
                OutputName = Recognition(strcat(PathName,ImageName), m, A, Eigenfaces); % outputname = name of the image
                if (strcmp(OutputName,'None'))
                    prompt = {'Could not find tag for image. Please enter tag','Additional tag (Optional)'};
                    dlg_title = 'Tag Input';
                    num_lines= 1 ;
                    def = {'',''} ;
                    options.WindowStyle = 'normal' ;
                    input  = inputdlg(prompt,dlg_title,num_lines,def,options);
                    tag = strcat(input{1},'~') ;
                    if (strcmp(input{2},'') ~= 1)
                       tag = strcat(input{2},'~') ;
                    end
                    tag1 = input{1} ;
                    tag2 = input{2} ;
                    imwrite(NewImage,FullName,'jpg','Comment',tag) ;
                    app.TextArea.Visible = 'on' ;
                    app.TextArea.Value = {'Image tagged as :';tag1;tag2} ;
  
                    T = CreateDatabase(ImageDir) ;
                else
                   OutputName = strcat('.\Images\',OutputName);
                   info = imfinfo(OutputName);
                   tag = char(info.Comment) 
                   tags = strsplit(tag,'~') ;
                   tag1 = tags{1} ;
                   tag2 = tags{2} ;
                   app.TextArea.Visible = 'on' ;
                   app.TextArea.Value = {'Image tagged as :';tag1;tag2} ;
                    app.RecognitionVerificationPanel.Visible = 'on' ;           
                   %imwrite(NewImage,FullName,'jpg','Comment',tag) ;
                end
            end
            
            %add photo in ablum for path
            % checking for tag 1
             
            if (exist(tag1,'dir') == 7)
                AlbumName = strcat('.\',tag1) ;
                AlbumName = strcat(AlbumName,'\') ;
                AlbumName = strcat(AlbumName,ImageName) ;
                imwrite(NewImage,AlbumName,'jpg','Comment',tag) ;
            end
            
            if (exist(tag2,'dir') == 7)
                AlbumName = strcat('.\',tag2) ;
                AlbumName = strcat(AlbumName,'\') ;
                AlbumName = strcat(AlbumName,ImageName) ;
                imwrite(NewImage,AlbumName,'jpg','Comment',tag) ;
            end
            % checking for tag 2
            
        end

        % Callback function
        function AddImageTabSizeChanged(app, event)
            position = app.AddImageTab.Position;
            set(app.Photo_Album_Organizer,'AutoResizeChildren','off' ) ;
            set(app.UIAxes,'Visibility','off');
            set(app.TextArea,'Visibility','off');
        end

        % Button pushed function: CreateAlbumButton
        function CreateAlbumButtonPushed(app, event)
            global ImageDir ;
            ImageDir =  dir('.\Images')  ;
            Tag  = app.EnterTagEditField.Value
            count = 0 ;
            name = {};
            pathname = {};
            
            %counting images
            for i = 3 : length(ImageDir)
                imgName = strcat('.\Images\',ImageDir(i).name);
                disp(imgName) ; 
                info = imfinfo(imgName) ;
                comment = char(info.Comment)
                tags = strsplit(comment,'~') ;
                disp(tags) ;
                if (strcmp(tags{1},Tag) || strcmp(tags{2},Tag) )
                    pathname{end+1} = imgName ;
                    name{end+1} = ImageDir(i).name ;
                    count = count + 1 ;
                end 
            end
            
            
            if count > 0
                % creating album
                 AlbumPath = strcat (pwd,'\') ;
                 AlbumPath = strcat (AlbumPath,Tag) ;
                 %AlbumPath = strcat (AlbumPath,'\') ;
                 status = mkdir(Tag) ;
                 disp(status)
                for k = 1 : count
                    Photo = imread(pathname{k}) ;
                    PhotoName = strcat(AlbumPath,'\') ;
                    PhotoName = strcat(PhotoName,name{k}) ;
                    imwrite(Photo,PhotoName);
                end
            end    
            
            message = strcat('Album created at :',AlbumPath) ; 
            h = msgbox(message,'Success');
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            global ImageDir ;
            ImageDir =  dir('.\Images')  ;
            Tag  = app.EnterTagEditField.Value
            count = 0 ;
            names = {};
            
            %counting images
            for i = 3 : length(ImageDir)
                imgName = strcat('.\Images\',ImageDir(i).name);
                info = imfinfo(imgName) ;
                comment = char(info.Comment)
                tags = strsplit(comment,'~') ;
                disp(tags) ;
                if (strcmp(tags{1},Tag) || strcmp(tags{2},Tag) )
                    names{end+1} = imgName ;
                    count = count + 1 ;
                end 
            end
              
            % displaying images
            if count > 0
                size = ceil(log2(count)) + 1;
                f1 = figure;
                for k = 1 : count
                    subplot (size,size,k,'Parent', f1) ;
                     imshow(names{k}) ;
                end
            end      
        end

        % Callback function
        function SelectPathEditFieldValueChanging(app, event)
           
        end

        % Button pushed function: BrowseButton
        function browseImage(app, event)
            global ImageName ;
            global PathName ;
            [ImageName,PathName] = uigetfile('*.jpg', 'Select an image to add') ;
            app.SelectPathEditField.Value = strcat(PathName,ImageName) ;
         
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
             global NewImage  ;
             global FullName ;
             global tag;
             
            imwrite(NewImage,FullName,'jpg','Comment',tag) ;
            app.RecognitionVerificationPanel.Visible = 'off' ;
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
             global NewImage  ;
             global FullName ;
            prompt = {'Oops! Sorry !!!. Please enter tag','Additional tag (Optional)'};
                    dlg_title = 'Oops! Sorry !!!';
                    num_lines= 1 ;
                    def = {'',''} ;
                    options.WindowStyle = 'normal' ;
                    input  = inputdlg(prompt,dlg_title,num_lines,def,options);
                    tag = strcat(input{1},'~') ;
                    if (strcmp(input{2},'') ~= 1)
                       tag = strcat(input{2},'~') ;
                    end
                    tag1 = input{1} ;
                    tag2 = input{2} ;
                    imwrite(NewImage,FullName,'jpg','Comment',tag) ;
                    app.TextArea.Visible = 'on' ;
                    app.TextArea.Value = {'Image tagged as :';tag1;tag2} ;
                    app.RecognitionVerificationPanel.Visible = 'off' ;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Photo_Album_Organizer = uifigure;
            app.Photo_Album_Organizer.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.Photo_Album_Organizer);
            app.AddImageTab = uitab(app.TabGroup);
            app.AddImageTab.Title = 'Add Image';
            app.SelectPathEditFieldLabel = uilabel(app.AddImageTab);
            app.SelectPathEditFieldLabel.Text = 'Select Path';
            app.SelectPathEditField = uieditfield(app.AddImageTab, 'text');
            app.BrowseButton = uibutton(app.AddImageTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browseImage, true);
            app.BrowseButton.Text = 'Browse';
            app.AddButton = uibutton(app.AddImageTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddImage, true);
            app.AddButton.Text = 'Add';
            app.UIAxes = uiaxes(app.AddImageTab);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLim = [0 1];
            app.UIAxes.CLimMode = 'manual';
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.Label = uilabel(app.AddImageTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.AddImageTab);
            app.TextArea.Editable = 'off';
            app.RecognitionVerificationPanel = uipanel(app.AddImageTab);
            app.RecognitionVerificationPanel.Title = 'Was the recognition correct ?';
            app.YesButton = uibutton(app.RecognitionVerificationPanel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.RecognitionVerificationPanel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.SearchImageTab = uitab(app.TabGroup);
            app.SearchImageTab.Title = 'Search Image';
            app.EnterTagEditFieldLabel = uilabel(app.SearchImageTab);
            app.EnterTagEditFieldLabel.Text = 'Enter Tag ';
            app.EnterTagEditField = uieditfield(app.SearchImageTab, 'text');
            app.SearchButton = uibutton(app.SearchImageTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.CreateAlbumButton = uibutton(app.SearchImageTab, 'push');
            app.CreateAlbumButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAlbumButtonPushed, true);
            app.CreateAlbumButton.Text = 'Create Album';
            app.Label_2 = uilabel(app.Photo_Album_Organizer);
            app.Label_2.Text = '';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Photo_Album_Organizer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Photo_Album_Organizer)
        end
    end
end